classdef LC2RC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TesttxtButton               matlab.ui.control.Button
        backfilterEditField         matlab.ui.control.NumericEditField
        backfilterEditFieldLabel    matlab.ui.control.Label
        frontfilterEditField        matlab.ui.control.NumericEditField
        frontfilterEditFieldLabel   matlab.ui.control.Label
        NextStepButton              matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        SaveButton                  matlab.ui.control.Button
        DataProcessingOptionsLabel  matlab.ui.control.Label
        Tree                        matlab.ui.container.CheckBoxTree
        ChannelsincludedNode        matlab.ui.container.TreeNode
        Channel1Node                matlab.ui.container.TreeNode
        Channel2Node                matlab.ui.container.TreeNode
        Channel3Node                matlab.ui.container.TreeNode
        Channel4Node                matlab.ui.container.TreeNode
        Channel5Node                matlab.ui.container.TreeNode
        Channel6Node                matlab.ui.container.TreeNode
        ProcessButton               matlab.ui.control.Button
        FilePathEditField           matlab.ui.control.EditField
        FilePathEditFieldLabel      matlab.ui.control.Label
        PlotR2R1RatioCheckBox       matlab.ui.control.CheckBox
        PlotforMuscleSpineCheckBox  matlab.ui.control.CheckBox
        NormalizeCheckBox           matlab.ui.control.CheckBox
        SavePlotasCSVCheckBox       matlab.ui.control.CheckBox
        IncludeR2CheckBox           matlab.ui.control.CheckBox
        DelayEditField              matlab.ui.control.NumericEditField
        DelayEditFieldLabel         matlab.ui.control.Label
        IntervalEditField           matlab.ui.control.NumericEditField
        IntervalEditFieldLabel      matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Phase = 1 % 1: window adjustment, 2 = data plotting 
        %Makesure WindowEditField is default 100 
        LabChart ; 
        mat_file_name ;
       
    end


    methods (Access = private)
        
        function plotRC(app)
            %Insert the plotting function here, pltot to the axes
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % When pushed, the button will plot depending on the phase the app is
            % in

            window_size = app.IntervalEditField.Value;
            delay = app.DelayEditField; 


            switch app.Phase
                case 1 % window adjusting
                    %should plot the window, and the delay, overlaying a
                    %sample piece of data (maybe channel 3 at one of the
                    %later amplitudes 

                    %Calls the plot RC function, but modified s.t. it takes
                    %in 
               
                case 2 %do plot rc
                   
                otherwise disp('error phase is not 1 or 2, call the programmer to debug')
            end 

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            % %DEMO: for now, run AutomationDraft
            % %TODO: more advanced function 
            % AutomationDraft(app.FilePathEditField.Value);


            %Load adi into path
            addpath adinstruments_sdk_matlab-master


            %If the string contains the symbols \"\", get rid of them
            app.FilePathEditField.Value = erase(app.FilePathEditField.Value, '"');
            
            %If no extension, look for file_name.mat first, then
            %file_name.adicht in the given directory. 
            if ~isfile(app.FilePathEditField.Value)
               if isfile([app.FilePathEditField.Value,'.mat'])
                   app.FilePathEditField.Value = [app.FilePathEditField.Value,'.mat'];
               elseif isfile([app.FilePathEditField.Value,'.adicht'])
                  app.FilePathEditField.Value = [app.FilePathEditField.Value,'.adicht'];
               else 
                   error("file format incorrect")
                end
            end


            %TODO: parse the filepath for its extensions 
            [~, file_name, ext] = fileparts(app.FilePathEditField.Value);
           
            % Preprocesses the data if .adicht file
            if strcmpi(ext, ".adicht")
                app.LabChart = load(AlexConvert(app.FilePathEditField.Value)); % This should load everything into workspace 

            % Load data if .mat file, but first attempt to save to input  
            elseif strcmpi(ext, ".mat")

                [parent, ~] = fileparts(mfilename('fullpath'));
                newfilepath = [parent, '/Input/' , file_name, '.mat'];
                copyfile((app.FilePathEditField.Value, newfilepath);
                app.LabChart = load(app.FilePathEditField.Value); % Load matlab file into App memory 
                

                %If the matlab file is bad, there will be error thrown
                %later 

            else 
                error("File of incorrect extension, only .adicht and .mat files are supported")
            end

            app.Data = app.Labchart.Data          ;
            app.file_meta = app.Labchart.file_meta     ;
            app.comments = app.Labchart.comments      ;
            app.record_meta = app.Labchart.record_meta   ;
            app.channel_meta = app.Labchart.channel_meta  ;

            %Here, print to the textarea 

            app.TextArea.Value = 'New file loading completed...';


            % === Stimulation Location Determination ===
            % DONE
            % 1. identify the peaks in channel 2
                % This is done by normalizing channel 2 to be 0 to 1, every spot
                % greater than .8 is going to be a stimulation
                % associate the amplitude comment to the nearest 2 stimulations 
                % Alternatively, assuming that channel 2 format is standard, we can
                % forgo normalizing and just find spots greater than 1
            % 3. notate each stimulation in the comments section  
            % NExt step, is to make sure the new format can determine R1 & r2
            
            % determine the records we care about, to reduce running time 
            records = unique([comments.record]);
            irrelevant_records = [];
            
            % Go through each relevant record and determine all the stimulations by
            % looking at channel 2
            % Then add each of the stimulations to comments
            for idx = 1:length(records)
                i = records(idx);
            
                % extract channel 2 in the given record
                chnl2 = Data{1,i}(2,:); % ith column of the Data cell, everything in row 2
                % find the indices of stimulations as a list (i.e. tick position) 
                indices = find(chnl2 > 2); 
            
                if width(indices) == 0
                    irrelevant_records(end+1) = i;
                    continue
                end
            
                %Remove repetitive stimulations
                diffs = diff(indices);
                boundaries = [1 , find(diffs>stim_interval+3) + 1];
                filtered_indices = indices(boundaries);
                
                % for loop add them as str = 'stim', tick_position = index, record = i
                len = length(comments);
                for j = 1:length(filtered_indices)
                    %index = filtered_indices(j)
                    len = len + 1;
                    comments(len).str = 'stim';
                    comments(len).tick_position = filtered_indices(j);
                    comments(len).record = i;
                end
            end
            
            records = setdiff(records,irrelevant_records);
            
            clearvars filtered_indices indices diffs boundaries i idx j irrelevant_records

            app.TextArea.Value = "Relevant records identified as " + int2str(records);


            % Should Process the data
            % Should create nodes in the channels included tree
            % Should show raw comments to error check
            % Shou


        end

        % Callback function: not associated with a component
        function LoadButtonPushed(app, event)
            app.LabChart = load(app.FilePathEditField.Value);


        end

        % Button pushed function: TesttxtButton
        function TesttxtButtonPushed(app, event)
            app.TextArea.Value = fileparts(mfilename('fullpath'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.IntervalEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalEditField.Value = 100;
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField.Value = 130;
            app.IncludeR2CheckBox = uicheckbox(app.UIFigure);
            app.IncludeR2CheckBox.Text = 'Include R2';
            app.IncludeR2CheckBox.Value = true;
            app.SavePlotasCSVCheckBox = uicheckbox(app.UIFigure);
            app.SavePlotasCSVCheckBox.Text = 'Save Plot as CSV';
            app.SavePlotasCSVCheckBox.Value = true;
            app.NormalizeCheckBox = uicheckbox(app.UIFigure);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.PlotforMuscleSpineCheckBox = uicheckbox(app.UIFigure);
            app.PlotforMuscleSpineCheckBox.Text = 'Plot for Muscle / Spine';
            app.PlotR2R1RatioCheckBox = uicheckbox(app.UIFigure);
            app.PlotR2R1RatioCheckBox.Text = 'Plot R2/R1 Ratio ';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Value = 'C:\Users\fengy\Desktop\HM\20240826_RTA006_EPA1_RC ONLY.adicht';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.ChannelsincludedNode = uitreenode(app.Tree);
            app.ChannelsincludedNode.Text = 'Channels included';
            app.Channel1Node = uitreenode(app.ChannelsincludedNode);
            app.Channel1Node.Text = 'Channel1';
            app.Channel2Node = uitreenode(app.ChannelsincludedNode);
            app.Channel2Node.Text = 'Channel2';
            app.Channel3Node = uitreenode(app.ChannelsincludedNode);
            app.Channel3Node.Text = 'Channel3';
            app.Channel4Node = uitreenode(app.ChannelsincludedNode);
            app.Channel4Node.Text = 'Channel4';
            app.Channel5Node = uitreenode(app.ChannelsincludedNode);
            app.Channel5Node.Text = 'Channel5';
            app.Channel6Node = uitreenode(app.ChannelsincludedNode);
            app.Channel6Node.Text = 'Channel6';
            app.DataProcessingOptionsLabel = uilabel(app.UIFigure);
            app.DataProcessingOptionsLabel.Text = 'Data Processing Options';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.TextArea = uitextarea(app.UIFigure);
            app.NextStepButton = uibutton(app.UIFigure, 'push');
            app.NextStepButton.Text = 'Next Step';
            app.frontfilterEditFieldLabel = uilabel(app.UIFigure);
            app.frontfilterEditFieldLabel.Text = 'frontfilter';
            app.frontfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.frontfilterEditField.Value = 20;
            app.backfilterEditFieldLabel = uilabel(app.UIFigure);
            app.backfilterEditFieldLabel.Text = 'backfilter';
            app.backfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.backfilterEditField.Value = 15;
            app.TesttxtButton = uibutton(app.UIFigure, 'push');
            app.TesttxtButton.ButtonPushedFcn = createCallbackFcn(app, @TesttxtButtonPushed, true);
            app.TesttxtButton.Text = 'Testtxt';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LC2RC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end